classdef calculadora < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        MenuButton               matlab.ui.control.Button
        txtr1                    matlab.ui.control.NumericEditField
        RespuestaEditFieldLabel  matlab.ui.control.Label
        RaizButton               matlab.ui.control.Button
        Button_5                 matlab.ui.control.Button
        Button_4                 matlab.ui.control.Button
        Button_3                 matlab.ui.control.Button
        Button_2                 matlab.ui.control.Button
        Button                   matlab.ui.control.Button
        txt2                     matlab.ui.control.NumericEditField
        Numero2EditFieldLabel    matlab.ui.control.Label
        txt1                     matlab.ui.control.NumericEditField
        Numero1EditFieldLabel    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            num1=app.txt1.Value
            num2=app.txt2.Value
            app.txtr1.Value=multiplicacion(num1,num2)
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
             num1=app.txt1.Value
            num2=app.txt2.Value
            app.txtr1.Value=suma(num1,num2)
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
             num1=app.txt1.Value
            num2=app.txt2.Value
            app.txtr1.Value=resta(num1,num2)
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
             num1=app.txt1.Value
            num2=app.txt2.Value
            app.txtr1.Value=potencia(num1,num2)
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
             num1=app.txt1.Value
            num2=app.txt2.Value
            app.txtr1.Value=division(num1,num2)
        end

        % Button pushed function: RaizButton
        function RaizButtonPushed(app, event)
             num1=app.txt1.Value
            app.txtr1.Value=raiz(num1)
        end

        % Button pushed function: MenuButton
        function MenuButtonPushed(app, event)
            delete(app)
            menu
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Numero1EditFieldLabel = uilabel(app.UIFigure);
            app.Numero1EditFieldLabel.Text = 'Numero 1';
            app.txt1 = uieditfield(app.UIFigure, 'numeric');
            app.Numero2EditFieldLabel = uilabel(app.UIFigure);
            app.Numero2EditFieldLabel.Text = 'Numero 2';
            app.txt2 = uieditfield(app.UIFigure, 'numeric');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '*';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '+';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '-';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '^';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '/';
            app.RaizButton = uibutton(app.UIFigure, 'push');
            app.RaizButton.ButtonPushedFcn = createCallbackFcn(app, @RaizButtonPushed, true);
            app.RaizButton.Text = 'Raiz';
            app.RespuestaEditFieldLabel = uilabel(app.UIFigure);
            app.RespuestaEditFieldLabel.Text = 'Respuesta';
            app.txtr1 = uieditfield(app.UIFigure, 'numeric');
            app.MenuButton = uibutton(app.UIFigure, 'push');
            app.MenuButton.ButtonPushedFcn = createCallbackFcn(app, @MenuButtonPushed, true);
            app.MenuButton.Text = 'Menu';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = calculadora

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end