classdef form3y3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        MenuButton        matlab.ui.control.Button
        txtr3             matlab.ui.control.NumericEditField
        EditField_3Label  matlab.ui.control.Label
        txtr2             matlab.ui.control.NumericEditField
        EditField_2Label  matlab.ui.control.Label
        txtr1             matlab.ui.control.NumericEditField
        Label             matlab.ui.control.Label
        txt9              matlab.ui.control.NumericEditField
        z3EditFieldLabel  matlab.ui.control.Label
        txt8              matlab.ui.control.NumericEditField
        y3EditFieldLabel  matlab.ui.control.Label
        txt7              matlab.ui.control.NumericEditField
        x3EditFieldLabel  matlab.ui.control.Label
        tabla             matlab.ui.control.Table
        GraficaButton     matlab.ui.control.Button
        ResolverButton    matlab.ui.control.Button
        txt6              matlab.ui.control.NumericEditField
        z2EditFieldLabel  matlab.ui.control.Label
        txt5              matlab.ui.control.NumericEditField
        y2EditFieldLabel  matlab.ui.control.Label
        txt4              matlab.ui.control.NumericEditField
        x2EditFieldLabel  matlab.ui.control.Label
        txt3              matlab.ui.control.NumericEditField
        z1EditFieldLabel  matlab.ui.control.Label
        txt2              matlab.ui.control.NumericEditField
        y1EditFieldLabel  matlab.ui.control.Label
        txt1              matlab.ui.control.NumericEditField
        x1EditFieldLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ResolverButton
        function ResolverButtonPushed(app, event)
            num1=app.txt1.Value
            num2=app.txt2.Value
            num3=app.txt3.Value
            r1=app.txtr1.Value
            num4=app.txt4.Value
            num5=app.txt5.Value
            num6=app.txt6.Value
            r2=app.txtr2.Value
            num7=app.txt7.Value
            num8=app.txt8.Value
            num9=app.txt9.Value
            r3=app.txtr3.Value
            app.tabla.Data=funcion3y3(num1,num2,num3,r1,num4,num5,num6,r2,num7,num8 ,num9,r3)
        end

        % Button pushed function: GraficaButton
        function GraficaButtonPushed(app, event)
            num1=app.txt1.Value
            num2=app.txt2.Value
            num3=app.txt3.Value
            r1=app.txtr1.Value
            num4=app.txt4.Value
            num5=app.txt5.Value
            num6=app.txt6.Value
            r2=app.txtr2.Value
            num7=app.txt7.Value
            num8=app.txt8.Value
            num9=app.txt9.Value
            r3=app.txtr3.Value
            graifca3y3(num1,num2,num3,r1,num4,num5,num6,r2,num7,num8 ,num9,r3)
        end

        % Button pushed function: MenuButton
        function MenuButtonPushed(app, event)
            delete(app)
            menu
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.x1EditFieldLabel = uilabel(app.UIFigure);
            app.x1EditFieldLabel.Text = 'x1';
            app.txt1 = uieditfield(app.UIFigure, 'numeric');
            app.y1EditFieldLabel = uilabel(app.UIFigure);
            app.y1EditFieldLabel.Text = 'y1';
            app.txt2 = uieditfield(app.UIFigure, 'numeric');
            app.z1EditFieldLabel = uilabel(app.UIFigure);
            app.z1EditFieldLabel.Text = 'z1';
            app.txt3 = uieditfield(app.UIFigure, 'numeric');
            app.x2EditFieldLabel = uilabel(app.UIFigure);
            app.x2EditFieldLabel.Text = 'x2';
            app.txt4 = uieditfield(app.UIFigure, 'numeric');
            app.y2EditFieldLabel = uilabel(app.UIFigure);
            app.y2EditFieldLabel.Text = 'y2';
            app.txt5 = uieditfield(app.UIFigure, 'numeric');
            app.z2EditFieldLabel = uilabel(app.UIFigure);
            app.z2EditFieldLabel.Text = 'z2';
            app.txt6 = uieditfield(app.UIFigure, 'numeric');
            app.ResolverButton = uibutton(app.UIFigure, 'push');
            app.ResolverButton.ButtonPushedFcn = createCallbackFcn(app, @ResolverButtonPushed, true);
            app.ResolverButton.Text = 'Resolver';
            app.GraficaButton = uibutton(app.UIFigure, 'push');
            app.GraficaButton.ButtonPushedFcn = createCallbackFcn(app, @GraficaButtonPushed, true);
            app.GraficaButton.Text = 'Grafica';
            app.tabla = uitable(app.UIFigure);
            app.tabla.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.tabla.RowName = {};
            app.x3EditFieldLabel = uilabel(app.UIFigure);
            app.x3EditFieldLabel.Text = 'x3';
            app.txt7 = uieditfield(app.UIFigure, 'numeric');
            app.y3EditFieldLabel = uilabel(app.UIFigure);
            app.y3EditFieldLabel.Text = 'y3';
            app.txt8 = uieditfield(app.UIFigure, 'numeric');
            app.z3EditFieldLabel = uilabel(app.UIFigure);
            app.z3EditFieldLabel.Text = 'z3';
            app.txt9 = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '=';
            app.txtr1 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = '=';
            app.txtr2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3Label = uilabel(app.UIFigure);
            app.EditField_3Label.Text = '=';
            app.txtr3 = uieditfield(app.UIFigure, 'numeric');
            app.MenuButton = uibutton(app.UIFigure, 'push');
            app.MenuButton.ButtonPushedFcn = createCallbackFcn(app, @MenuButtonPushed, true);
            app.MenuButton.Text = 'Menu';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = form3y3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end