classdef formularioB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        tabla                   matlab.ui.control.Table
        EliminarButton          matlab.ui.control.Button
        AnularButton            matlab.ui.control.Button
        ModificarButton         matlab.ui.control.Button
        GuardarButton           matlab.ui.control.Button
        txtY                    matlab.ui.control.NumericEditField
        EditField2Label         matlab.ui.control.Label
        txtX                    matlab.ui.control.NumericEditField
        EditFieldLabel          matlab.ui.control.Label
        FormulariodedatosLabel  matlab.ui.control.Label
        ValorYLabel             matlab.ui.control.Label
        ValorXLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        idDAtoG % Description
    end
    
    methods (Access = private)
        
        function results = mostrar(app)
            app.tabla.Data=consultaDato
            app.txtX.Value=0
            app.txtY.Value=0     
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            mostrar(app)
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            valorX=app.txtX.Value
            valorY=app.txtY.Value
            insertarDato(valorX,valorY,1)
            msgbox("Registro insertado")
            mostrar(app)

        end

        % Selection changed function: tabla
        function tablaSelectionChanged(app, event)
            selection = app.tabla.Selection;
            if ~isempty(selection)
                filaSeleccionada=selection(1)
                app.idDAtoG=app.tabla.Data{filaSeleccionada,1}
                datoX=app.tabla.Data{filaSeleccionada,2}
                datoY=app.tabla.Data{filaSeleccionada,3}
                app.txtX.Value=datoX
                app.txtY.Value=datoY
            end
        end

        % Button pushed function: ModificarButton
        function ModificarButtonPushed(app, event)
            datoX=app.txtX.Value
            datoY=app.txtY.Value
            actualizarDato(app.idDAtoG,datoX,datoY,1)
            msgbox("Registro Modificado")
            mostrar(app)
        end

        % Button pushed function: AnularButton
        function AnularButtonPushed(app, event)
            anularDato(app.idDAtoG)
            msgbox("Registro Anulado")
            mostrar(app)
        end

        % Button pushed function: EliminarButton
        function EliminarButtonPushed(app, event)
            eliminarDato(app.idDAtoG)
            msgbox("Registro Eliminado")
            mostrar(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ValorXLabel = uilabel(app.UIFigure);
            app.ValorXLabel.Text = 'Valor X';
            app.ValorYLabel = uilabel(app.UIFigure);
            app.ValorYLabel.Text = 'Valor Y';
            app.FormulariodedatosLabel = uilabel(app.UIFigure);
            app.FormulariodedatosLabel.Text = 'Formulario de datos';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.txtX = uieditfield(app.UIFigure, 'numeric');
            app.EditField2Label = uilabel(app.UIFigure);
            app.EditField2Label.Text = 'Edit Field2';
            app.txtY = uieditfield(app.UIFigure, 'numeric');
            app.GuardarButton = uibutton(app.UIFigure, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.ModificarButton = uibutton(app.UIFigure, 'push');
            app.ModificarButton.ButtonPushedFcn = createCallbackFcn(app, @ModificarButtonPushed, true);
            app.ModificarButton.Text = 'Modificar';
            app.AnularButton = uibutton(app.UIFigure, 'push');
            app.AnularButton.ButtonPushedFcn = createCallbackFcn(app, @AnularButtonPushed, true);
            app.AnularButton.Text = 'Anular';
            app.EliminarButton = uibutton(app.UIFigure, 'push');
            app.EliminarButton.ButtonPushedFcn = createCallbackFcn(app, @EliminarButtonPushed, true);
            app.EliminarButton.Text = 'Eliminar';
            app.tabla = uitable(app.UIFigure);
            app.tabla.ColumnName = {'Id'; 'Valor X'; 'Valor Y'};
            app.tabla.RowName = {};
            app.tabla.SelectionChangedFcn = createCallbackFcn(app, @tablaSelectionChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = formularioB

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end