classdef menu_geometria < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LibrasakilogramosButton         matlab.ui.control.Button
        MetrosapiesButton               matlab.ui.control.Button
        HorasasegundosButton            matlab.ui.control.Button
        AreacirculoButton               matlab.ui.control.Button
        AreayperimetrorectanguloButton  matlab.ui.control.Button
        CalculadorageomtricaLabel       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AreayperimetrorectanguloButton
        function AreayperimetrorectanguloButtonPushed(app, event)
            area_y_perimetro
            app.UIFigure.Visible='off'
        end

        % Button pushed function: AreacirculoButton
        function AreacirculoButtonPushed(app, event)
            calcular_area_circulo
            app.UIFigure.Visible='off'
        end

        % Button pushed function: HorasasegundosButton
        function HorasasegundosButtonPushed(app, event)
            hora_a_segundos
            app.UIFigure.Visible='off'
        end

        % Button pushed function: MetrosapiesButton
        function MetrosapiesButtonPushed(app, event)
           conv_metros_a_pies
           app.UIFigure.Visible='off'
        end

        % Button pushed function: LibrasakilogramosButton
        function LibrasakilogramosButtonPushed(app, event)
            libras_a_kilogramos
            app.UIFigure.Visible='off'
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CalculadorageomtricaLabel = uilabel(app.UIFigure);
            app.CalculadorageomtricaLabel.Text = 'Calculadora geométrica';
            app.AreayperimetrorectanguloButton = uibutton(app.UIFigure, 'push');
            app.AreayperimetrorectanguloButton.ButtonPushedFcn = createCallbackFcn(app, @AreayperimetrorectanguloButtonPushed, true);
            app.AreayperimetrorectanguloButton.Text = 'Area y perimetro rectangulo';
            app.AreacirculoButton = uibutton(app.UIFigure, 'push');
            app.AreacirculoButton.ButtonPushedFcn = createCallbackFcn(app, @AreacirculoButtonPushed, true);
            app.AreacirculoButton.Text = 'Area circulo';
            app.HorasasegundosButton = uibutton(app.UIFigure, 'push');
            app.HorasasegundosButton.ButtonPushedFcn = createCallbackFcn(app, @HorasasegundosButtonPushed, true);
            app.HorasasegundosButton.Text = 'Horas a segundos';
            app.MetrosapiesButton = uibutton(app.UIFigure, 'push');
            app.MetrosapiesButton.ButtonPushedFcn = createCallbackFcn(app, @MetrosapiesButtonPushed, true);
            app.MetrosapiesButton.Text = 'Metros a pies';
            app.LibrasakilogramosButton = uibutton(app.UIFigure, 'push');
            app.LibrasakilogramosButton.ButtonPushedFcn = createCallbackFcn(app, @LibrasakilogramosButtonPushed, true);
            app.LibrasakilogramosButton.Text = 'Libras a kilogramos';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = menu_geometria

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end